classdef Logging_MultiCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LastCommandEditField          matlab.ui.control.EditField
        LastCommandEditFieldLabel     matlab.ui.control.Label
        SincelastcommandhrsEditField  matlab.ui.control.NumericEditField
        SincelastcommandhrsEditFieldLabel  matlab.ui.control.Label
        SCRAMButton                   matlab.ui.control.Button
        SincelastcommandsEditField    matlab.ui.control.NumericEditField
        SincelastcommandsEditFieldLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        ProgramTab                    matlab.ui.container.Tab
        ProgramModeSwitch             matlab.ui.container.ButtonGroup
        SWButton                      matlab.ui.control.RadioButton
        ArbButton                     matlab.ui.control.RadioButton
        TabGroup2                     matlab.ui.container.TabGroup
        ArbitraryTab                  matlab.ui.container.Tab
        StartfirsttimepointwithNonzerocurrentcommandLabel_2  matlab.ui.control.Label
        StartfirsttimepointwithNonzerocurrentcommandLabel  matlab.ui.control.Label
        InitcleararrayButton          matlab.ui.control.Button
        CommandsizeEditField          matlab.ui.control.NumericEditField
        CommandsizeEditFieldLabel     matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SwitchingTab                  matlab.ui.container.Tab
        NoteStimulationunitstoresmaxof5000timeponitsLabel  matlab.ui.control.Label
        HrMinSecLabel_3               matlab.ui.control.Label
        WillberoundedupifnotdivisiblebytickLabel_2  matlab.ui.control.Label
        WillberoundedupifnotdivisiblebytickLabel  matlab.ui.control.Label
        HrMinSecLabel_2               matlab.ui.control.Label
        HrMinSecLabel                 matlab.ui.control.Label
        WithXcurrentmALabel           matlab.ui.control.Label
        SWcurrTable                   matlab.ui.control.Table
        SWstimTime                    matlab.ui.control.NumericEditField
        StimdurationforallchannelEditFieldLabel  matlab.ui.control.Label
        SWctrlTime                    matlab.ui.control.NumericEditField
        ControlperiodforallchannelEditFieldLabel  matlab.ui.control.Label
        SWtickTime                    matlab.ui.control.NumericEditField
        TickeveryEditFieldLabel       matlab.ui.control.Label
        FieldswitcheveryXtickforeachchannelinitpositiveLabel  matlab.ui.control.Label
        SWperiodTable                 matlab.ui.control.Table
        TimeunitsSwitch               matlab.ui.container.ButtonGroup
        SecButton                     matlab.ui.control.RadioButton
        MinsButton                    matlab.ui.control.RadioButton
        HrsButton                     matlab.ui.control.RadioButton
        FormatcheckLampLabel          matlab.ui.control.Label
        FormatcheckLamp               matlab.ui.control.Lamp
        FormatPreviewButton           matlab.ui.control.Button
        ExecuteButton                 matlab.ui.control.Button
        PreviewPlot                   matlab.ui.control.UIAxes
        OverviewTab                   matlab.ui.container.Tab
        voltTable                     matlab.ui.control.Table
        currTable                     matlab.ui.control.Table
        VLabel                        matlab.ui.control.Label
        mALabel                       matlab.ui.control.Label
        resTable                      matlab.ui.control.Table
        KOhmLabel                     matlab.ui.control.Label
        secdelayLabel                 matlab.ui.control.Label
        ScrambeforeuseLabel           matlab.ui.control.Label
        SendPulse                     matlab.ui.control.Button
        Volt1_disp                    matlab.ui.control.LinearGauge
        Volt2_disp                    matlab.ui.control.LinearGauge
        Volt3_disp                    matlab.ui.control.LinearGauge
        Volt4_disp                    matlab.ui.control.LinearGauge
        Volt5_disp                    matlab.ui.control.LinearGauge
        Volt6_disp                    matlab.ui.control.LinearGauge
        Volt7_disp                    matlab.ui.control.LinearGauge
        Volt8_disp                    matlab.ui.control.LinearGauge
        Curr1_disp                    matlab.ui.control.LinearGauge
        Curr2_disp                    matlab.ui.control.LinearGauge
        Curr3_disp                    matlab.ui.control.LinearGauge
        Curr4_disp                    matlab.ui.control.LinearGauge
        Curr5_disp                    matlab.ui.control.LinearGauge
        Curr6_disp                    matlab.ui.control.LinearGauge
        Curr7_disp                    matlab.ui.control.LinearGauge
        Curr8_disp                    matlab.ui.control.LinearGauge
        ClearallplotButton            matlab.ui.control.Button
        DisconnectserialbeforeclearLabel  matlab.ui.control.Label
        TimeinplotandlogsEditField    matlab.ui.control.NumericEditField
        TimeinplotandlogLabel         matlab.ui.control.Label
        CurrPlot                      matlab.ui.control.UIAxes
        VoltPlot                      matlab.ui.control.UIAxes
        HeartbeatLamp                 matlab.ui.control.Lamp
        HeartbeatLampLabel            matlab.ui.control.Label
        PortSelectionLabel            matlab.ui.control.Label
        PortSelectionDropDown         matlab.ui.control.DropDown
        LoggingLampLabel              matlab.ui.control.Label
        LoggingLamp                   matlab.ui.control.Lamp
        SerialLampLabel               matlab.ui.control.Label
        SerialLamp                    matlab.ui.control.Lamp
        ConnectserialButton           matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        StartlogButton                matlab.ui.control.Button
        StoplogButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        DAQ;
        % Description
        serial_read_raw; % Description
        read_en;
        volt_arr;
        curr_arr;
        time_arr;
        raw_arr;
        incoming_volt;
        incoming_curr;
        incoming_res;
        log_en;
        logfile_full;
        colors = [1 0 0; 0 1 0; 0 0 1; 1 0.5 0; 1 0 1; 0 1 1; 0.5 0.5 0.5; 0.5 0 0];
        animatedVoltLines;
        animatedCurrLines;
        HB = 1;
        curr_CMD_arr;
        time_CMD_arr;
        CMD_length = 10;
    end

    methods (Access = private)

        function serial_init(app)
            try  % CHANGE: Added error handling
                app.DAQ = serialport(convertCharsToStrings(app.PortSelectionDropDown.Value), 9600, 'TimeOut', 10);
                configureTerminator(app.DAQ, "CR/LF");
                flush(app.DAQ);  % CHANGE: Flush input buffer to prevent old data reading
                app.SerialLamp.Color = 'Green';
            catch ME  % CHANGE: Handle errors during serial initialization
                app.SerialLamp.Color = 'Red';
                disp(['Error initializing serial: ', ME.message]);
            end
        end

        function serial_clr(app)
            if ~isempty(app.DAQ) && isvalid(app.DAQ)
                delete(app.DAQ);
            end
            app.DAQ = [];  % CHANGE: Ensure serial object is cleared
        end

        function serial_read_cont(app)
            % Data format: TIME::XXXX(since power on)::PGMTime::XXXX(since
            % program loaded timer)::SMPL::voltage x8 and current x8
            try  % CHANGE: Added error handling for reading
                while app.read_en
                    if app.DAQ.NumBytesAvailable > 0
                        incoming_raw = readline(app.DAQ);
                        if contains(incoming_raw, 'TIME::')
                            seg = strsplit(incoming_raw, '::');
                            incoming_time = str2double(seg{2});
                            incoming_PGM_time = str2double(seg{4});
                            app.SincelastcommandsEditField.Value = incoming_PGM_time;
                            app.SincelastcommandhrsEditField.Value = incoming_PGM_time/3600;
                            app.TimeinplotandlogsEditField.Value = incoming_time;
                            app.time_arr(end+1) = incoming_time / 3600;  % CHANGE: Pre-allocate if possible
                            incoming_data = str2double(strsplit(seg{6}, ' '));
                            app.incoming_volt = incoming_data(1:8);
                            app.incoming_curr = incoming_data(9:16);
                            app.incoming_res = round(app.incoming_volt ./ app.incoming_curr, 2);
                            app.volt_arr = [app.volt_arr; app.incoming_volt];
                            app.curr_arr = [app.curr_arr; app.incoming_curr];

                            app.update_plot();
                            app.update_gauge();

                            % Log Data if Enabled
                            if app.log_en
                                try  % CHANGE: Error handling for file I/O
                                    writematrix(incoming_raw, app.logfile_full, 'WriteMode', "append");
                                catch ME
                                    disp(['Error writing log: ', ME.message]);
                                    app.log_en = 0;  % Disable logging on error
                                end
                            end

                            % Update Heartbeat Indicator
                            app.HeartbeatLamp.Color = [app.HB, ~app.HB, 0];
                            app.HB = ~app.HB;
                        end
                    end
                    pause(0.05);  % CHANGE: Added small delay to prevent CPU overload
                end
            catch ME
                disp(['Error reading serial data: ', ME.message]);
                app.read_en = 0;  % Stop reading on error
            end
        end

        function update_gauge(app)
            app.voltTable.ColumnName = string(app.incoming_volt);
            app.currTable.ColumnName = string(app.incoming_curr);
            app.resTable.ColumnName = string(app.incoming_res);
            for i = 1:8
                voltField = sprintf('Volt%d_disp', i); % Create dynamic field name for voltage
                currField = sprintf('Curr%d_disp', i); % Create dynamic field name for current

                app.(voltField).Value = app.incoming_volt(i); % Set value for voltage
                %app.(voltField).FontColor = app.colors(i,:);
                app.(currField).Value = app.incoming_curr(i); % Set value for current
                %app.(currField).FontColor = app.colors(i,:);
            end
        end
        function update_plot(app)
            for j = 1:8
                addpoints(app.animatedCurrLines(j), app.time_arr(end), app.curr_arr(end,j));
                addpoints(app.animatedVoltLines(j), app.time_arr(end), app.volt_arr(end,j));
            end
            if length(app.time_arr) > 1
                newXLim = [app.time_arr(1), app.time_arr(end)];
            else
                % Default to a minimal range if there's not enough data
                % This assumes time_arr is not empty; adjust as necessary
                newXLim = [0, 0.01]; % or any default range appropriate for your application
            end

            xlim(app.CurrPlot, newXLim);
            xlim(app.VoltPlot, newXLim);
        end

        function switchPattern = generateSwitchPattern(app,numTicks, switchInterval)
            % generateSwitchPattern creates a 1/-1 pattern switching every X ticks.
            %
            % Inputs:
            %   numTicks       - total number of ticks (length of sequence)
            %   switchInterval - number of ticks before switching sign
            %
            % Output:
            %   switchPattern  - vector of 1 and -1 with switching pattern

            % Calculate number of segments needed
            if switchInterval == 0
                switchPattern = zeros(1,numTicks);
            else
            numSegments = ceil(numTicks / switchInterval);

            % Create 1 and -1 alternating sequence
            segmentValues = repmat([1, -1], 1, ceil(numSegments/2));
            segmentValues = segmentValues(1:numSegments); % trim to needed count

            % Expand each value into switchInterval-length segment
            switchPattern = repelem(segmentValues, switchInterval);

            % Trim to exact length
            switchPattern = switchPattern(1:numTicks);
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.serial_clr();
            app.SerialLamp.Color = 'Red';
            app.LoggingLamp.Color = 'Red';
            app.FormatcheckLamp.Color = 'White';
            app.read_en = 1;
            app.log_en = 0;
            candidate = serialportlist;
            app.PortSelectionDropDown.Items = candidate;
            app.voltTable.ColumnFormat = {'short'};
            app.currTable.ColumnFormat = {'short'};
            app.resTable.ColumnFormat = {'short'};
            for i = 1:8
                voltField = sprintf('Volt%d_disp', i); % Create dynamic field name for voltage
                currField = sprintf('Curr%d_disp', i); % Create dynamic field name for current
                app.(voltField).FontColor = app.colors(i,:); % Set value for voltage
                app.(currField).FontColor = app.colors(i,:);
            end
            app.animatedCurrLines = gobjects(1,8); % Pre-allocate an array of graphic objects
            app.animatedVoltLines = gobjects(1,8);
            for i = 1:8
                app.animatedCurrLines(i) = animatedline(app.CurrPlot, 'Color', app.colors(i,:), 'LineWidth', 2);
                app.animatedVoltLines(i) = animatedline(app.VoltPlot, 'Color', app.colors(i,:), 'LineWidth', 2);
            end
            app.SWcurrTable.Data = zeros(1,8);
            app.SWperiodTable.Data = zeros(1,8);

        end

        % Button pushed function: ConnectserialButton
        function ConnectserialButtonPushed(app, event)
            app.serial_init();
            if isvalid(app.DAQ)  % CHANGE: Check if serial object is valid
                app.read_en = 1;
                app.ClearallplotButton.Enable = 0;
                app.serial_read_cont();  % Start reading data
            else
                disp('Serial connection failed.');
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.read_en = 0;
            delete(app.DAQ);
            app.serial_clr();
            app.SerialLamp.Color = 'Red';
            app.ClearallplotButton.Enable = 1;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            candidate = serialportlist;
            app.PortSelectionDropDown.Items = candidate;

        end

        % Button pushed function: StartlogButton
        function StartlogButtonPushed(app, event)
            [log_path] = uigetdir;
            app.logfile_full = fullfile(log_path, strcat('Log_', datestr(now,'yyyymmdd-HHMM'), '.txt'));
            app.log_en = 1;
            app.LoggingLamp.Color = 'Green';
        end

        % Button pushed function: StoplogButton
        function StoplogButtonPushed(app, event)
            app.log_en = 0;
            app.LoggingLamp.Color = 'Red';

        end

        % Button pushed function: ClearallplotButton
        function ClearallplotButtonPushed(app, event)
            app.read_en = 0;

            % CHANGE: Clear all stored data
            app.volt_arr = [];
            app.curr_arr = [];
            app.time_arr = [];
            app.raw_arr = [];
            app.incoming_volt = [];
            app.incoming_curr = [];
            app.incoming_res = [];
            app.log_en = 0;
            app.LoggingLamp.Color = 'Red';
            app.HeartbeatLamp.Color = 'Red';
            app.ExecuteButton.Enable = 0;

            % CHANGE: Reset plots by clearing animated lines
            for i = 1:8
                clearpoints(app.animatedCurrLines(i));
                clearpoints(app.animatedVoltLines(i));
            end

            % CHANGE: Reset tables to default state
            app.voltTable.Data = {};  % Clear voltage table
            app.currTable.Data = {};  % Clear current table
            app.resTable.Data = {};   % Clear resistance table

            % CHANGE: Reset gauge values to 0
            for i = 1:8
                voltField = sprintf('Volt%d_disp', i);
                currField = sprintf('Curr%d_disp', i);
                app.(voltField).Value = 0;
                app.(currField).Value = 0;
            end

            % CHANGE: Reset plot axis limits
            xlim(app.CurrPlot, [0 5]);  % Default x-axis for current plot
            ylim(app.CurrPlot, [-5 5]);  % Default y-axis for current plot
            xlim(app.VoltPlot, [0 5]);  % Default x-axis for voltage plot
            ylim(app.VoltPlot, [-20 20]); % Default y-axis for voltage plot
        end

        % Button pushed function: InitcleararrayButton
        function InitcleararrayButtonPushed(app, event)
            app.CMD_length = app.CommandsizeEditField.Value;
            app.UITable.Data = zeros(app.CMD_length,9);
            app.curr_CMD_arr = zeros(app.CMD_length,8);
            app.time_CMD_arr = zeros(app.CMD_length);
            app.FormatcheckLamp.Color = 'White';
            ss = uistyle('FontColor', [0 0 0]);
            addStyle(app.UITable, ss);
            app.ExecuteButton.Enable = 0;
            cla(app.PreviewPlot);
        end

        % Button pushed function: FormatPreviewButton
        function FormatPreviewButtonPushed(app, event)
            if app.ArbButton.Value == 1
                %Reset for Arb mode arr to avoid conflicting with sw arr
                app.CMD_length = app.CommandsizeEditField.Value;
                app.curr_CMD_arr = zeros(app.CMD_length,8);
                app.time_CMD_arr = zeros(app.CMD_length);

                if app.HrsButton.Value == 1
                    app.time_CMD_arr = app.UITable.Data(:,1).*3600;
                elseif app.MinsButton.Value == 1
                    app.time_CMD_arr = app.UITable.Data(:,1).*60;
                else
                    app.time_CMD_arr = app.UITable.Data(:,1);
                end
                app.curr_CMD_arr = app.UITable.Data(:,2:9);

            elseif app.SWButton.Value == 1
                %Parse for switching value
                %Reset CMD arrs for switching to avoid carry over from Arb

                app.CMD_length = ceil((app.SWctrlTime.Value + app.SWstimTime.Value)/app.SWtickTime.Value )+ 1;
                app.curr_CMD_arr = zeros(app.CMD_length,8);
                app.time_CMD_arr = zeros(app.CMD_length);
                
                app.time_CMD_arr = 0:app.SWtickTime.Value:(app.SWtickTime.Value*(app.CMD_length-1));
                ctrl_tick = ceil(app.SWctrlTime.Value/app.SWtickTime.Value);

                % convert time based on unit selection
                if app.HrsButton.Value == 1
                    app.time_CMD_arr = app.time_CMD_arr.*3600;
                elseif app.MinsButton.Value == 1
                    app.time_CMD_arr = app.time_CMD_arr.*60;
                else
                    app.time_CMD_arr = app.time_CMD_arr;
                end
                app.time_CMD_arr (1) = 3; %delay 3 seconds for first command

                app.curr_CMD_arr(:, 1) = zeros;

                for i = 1:8
                    single_chnl_curr = [zeros(1,ctrl_tick), app.generateSwitchPattern(app.CMD_length-ctrl_tick-1, app.SWperiodTable.Data(1,i)), 0];
                    app.curr_CMD_arr (:, i) = single_chnl_curr.*app.SWcurrTable.Data(1,i);
                end

            end


            app.FormatcheckLamp.Color = 'Green';
            app.ExecuteButton.Enable = 1;
            ss = uistyle('FontColor', [0 0 0]);
            addStyle(app.UITable, ss);

            CurrAbsMax = (max(abs(app.curr_CMD_arr),[], 'all')+0.2);
            for i = 1:8
                currField = sprintf('Curr%d_disp', i); % Create dynamic field name for current
                app.(currField).Limits = [-CurrAbsMax, CurrAbsMax]; % set current gauge up/lower limit
            end
            

            ylim(app.CurrPlot, [-CurrAbsMax, CurrAbsMax]);  % Adjust y-axis for current plot

            cla(app.PreviewPlot)
            hold(app.PreviewPlot, 'on')


            t_preview = app.time_CMD_arr(:) ./ 3600;   % force column vector
            I_preview = app.curr_CMD_arr;              % size [N x 8]
            if numel(t_preview) ~= size(I_preview,1)
                error('Time and current rows mismatch: %d vs %d', numel(t_preview), size(I_preview,1));
            end
            nonzero_current = any(I_preview ~= 0, 2);  % [N x 1]
            valid_rows = (t_preview > 0) | nonzero_current;   % [N x 1], no NxN expansion
            t_preview = t_preview(valid_rows);
            I_preview  = I_preview(valid_rows, :);

            % Pad for stairs
            t_preview = [0; t_preview; t_preview(end)*1.5];
            I_preview = [zeros(1,8); I_preview; I_preview(end,:)];

            for ch = 1:8
                y = I_preview(:,ch)+(ch*CurrAbsMax*2); %offset each trace

                stairs(app.PreviewPlot, t_preview, y, ...
                    'Color', app.colors(ch,:), ...
                    'DisplayName', sprintf('Ch%d', ch), ...
                    'LineWidth', 1.5);
                yline(app.PreviewPlot,ch*CurrAbsMax*2); % "zero" line for each trace
            end
            if length(t_preview) < 11 % show only first 10 timestep to save computing power
                t_max = length(t_preview);
            else
                t_max = 11;
            end

            for t_step = 1:t_max-1
                xline(app.PreviewPlot,t_preview(t_step), '-.',num2str(t_preview(t_step)));
                
            end
            
            hold (app.PreviewPlot, 'off');

            % check for formatting errors
            for i = 1:app.CMD_length
                if app.time_CMD_arr(i) == 0
                    % Apply gray style
                    s = uistyle('FontColor', [0.6 0.6 0.6]);
                    addStyle(app.UITable, s, 'row', i);

                    % Check for non-zero current in this row
                    if any(app.curr_CMD_arr(i, :) ~= 0)
                        app.FormatcheckLamp.Color = 'red';
                        app.ExecuteButton.Enable = 0;
                    end
                end
            end

            if app.time_CMD_arr(1) == 0
                app.FormatcheckLamp.Color = 'red';
                app.ExecuteButton.Enable = 0;
            end

            if app.read_en == 0
                app.FormatcheckLamp.Color = 'red';
                app.ExecuteButton.Enable = 0;
            end

        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            % Parse the values into serial command
            curr_flat = reshape(app.curr_CMD_arr.', 1, []);
            time_str = strjoin(string(app.time_CMD_arr.'), ' ');
            curr_str = strjoin(string(curr_flat), ' ');
            command = sprintf('PGM::%d::%s %s', length(app.time_CMD_arr), time_str, curr_str);

            writeline(app.DAQ, command);
            app.LastCommandEditField.Value = command;
            app.SendPulse.Enable = 0;

        end

        % Button pushed function: SCRAMButton
        function SCRAMButtonPushed(app, event)
            if app.read_en
                writeline(app.DAQ, 'PGM::1::1 0 0 0 0 0 0 0 0');
                app.LastCommandEditField.Value = "ALL STOP";
                app.SendPulse.Enable = 1;
            end
        end

        % Button pushed function: SendPulse
        function SendPulseButtonPushed(app, event)
            if app.read_en
                writeline(app.DAQ, 'PGM::2::5 10 0.1 0.1 0.1 0.1 0.1 0.1 0.1 0.1 0 0 0 0 0 0 0 0');
                app.LastCommandEditField.Value = "pulse sent";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StoplogButton = uibutton(app.UIFigure, 'push');
            app.StoplogButton.ButtonPushedFcn = createCallbackFcn(app, @StoplogButtonPushed, true);
            app.StoplogButton.Text = 'Stop log';
            app.StartlogButton = uibutton(app.UIFigure, 'push');
            app.StartlogButton.ButtonPushedFcn = createCallbackFcn(app, @StartlogButtonPushed, true);
            app.StartlogButton.Text = 'Start log';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectserialButton = uibutton(app.UIFigure, 'push');
            app.ConnectserialButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectserialButtonPushed, true);
            app.ConnectserialButton.Text = 'Connect serial';
            app.SerialLamp = uilamp(app.UIFigure);
            app.SerialLampLabel = uilabel(app.UIFigure);
            app.SerialLampLabel.Text = 'Serial';
            app.LoggingLamp = uilamp(app.UIFigure);
            app.LoggingLampLabel = uilabel(app.UIFigure);
            app.LoggingLampLabel.Text = 'Logging';
            app.PortSelectionDropDown = uidropdown(app.UIFigure);
            app.PortSelectionLabel = uilabel(app.UIFigure);
            app.PortSelectionLabel.Text = 'Port Selection';
            app.HeartbeatLampLabel = uilabel(app.UIFigure);
            app.HeartbeatLampLabel.Text = 'Heart beat';
            app.HeartbeatLamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProgramTab = uitab(app.TabGroup);
            app.ProgramTab.Title = 'Program';
            app.PreviewPlot = uiaxes(app.ProgramTab);
            title(app.PreviewPlot, 'Current preview')
            xlabel(app.PreviewPlot, 'Time (hrs)')
            ylabel(app.PreviewPlot, 'Offset current CMD (mA)')
            zlabel(app.PreviewPlot, 'Z')
            app.ExecuteButton = uibutton(app.ProgramTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Enable = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.FormatPreviewButton = uibutton(app.ProgramTab, 'push');
            app.FormatPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @FormatPreviewButtonPushed, true);
            app.FormatPreviewButton.Text = 'Format/Preview';
            app.FormatcheckLamp = uilamp(app.ProgramTab);
            app.FormatcheckLampLabel = uilabel(app.ProgramTab);
            app.FormatcheckLampLabel.Text = 'Format check';
            app.TimeunitsSwitch = uibuttongroup(app.ProgramTab);
            app.TimeunitsSwitch.Title = 'Time units';
            app.HrsButton = uiradiobutton(app.TimeunitsSwitch);
            app.HrsButton.Text = 'Hrs';
            app.HrsButton.Value = true;
            app.MinsButton = uiradiobutton(app.TimeunitsSwitch);
            app.MinsButton.Text = 'Mins';
            app.SecButton = uiradiobutton(app.TimeunitsSwitch);
            app.SecButton.Text = 'Sec';
            app.TabGroup2 = uitabgroup(app.ProgramTab);
            app.ArbitraryTab = uitab(app.TabGroup2);
            app.ArbitraryTab.Title = 'Arbitrary';
            app.UITable = uitable(app.ArbitraryTab);
            app.UITable.ColumnName = {'Time'; 'Channel 1'; 'Channel 2'; 'Channel 3'; 'Channel 4'; 'Channel 5'; 'Channel 6'; 'Channel 7'; 'Channel 8'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.CommandsizeEditFieldLabel = uilabel(app.ArbitraryTab);
            app.CommandsizeEditFieldLabel.Text = 'Command size';
            app.CommandsizeEditField = uieditfield(app.ArbitraryTab, 'numeric');
            app.CommandsizeEditField.Value = 10;
            app.InitcleararrayButton = uibutton(app.ArbitraryTab, 'push');
            app.InitcleararrayButton.ButtonPushedFcn = createCallbackFcn(app, @InitcleararrayButtonPushed, true);
            app.InitcleararrayButton.Text = 'Init/clear array';
            app.StartfirsttimepointwithNonzerocurrentcommandLabel = uilabel(app.ArbitraryTab);
            app.StartfirsttimepointwithNonzerocurrentcommandLabel.Text = 'System default off when power on, start with non-zero current command';
            app.StartfirsttimepointwithNonzerocurrentcommandLabel_2 = uilabel(app.ArbitraryTab);
            app.StartfirsttimepointwithNonzerocurrentcommandLabel_2.Text = 'System stays on the last command, be sure to include all channel off command';
            app.SwitchingTab = uitab(app.TabGroup2);
            app.SwitchingTab.Title = 'Switching';
            app.SWperiodTable = uitable(app.SwitchingTab);
            app.SWperiodTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.SWperiodTable.RowName = {};
            app.SWperiodTable.ColumnEditable = true;
            app.FieldswitcheveryXtickforeachchannelinitpositiveLabel = uilabel(app.SwitchingTab);
            app.FieldswitcheveryXtickforeachchannelinitpositiveLabel.Text = 'Field switch every X tick for each channel (init positive)';
            app.TickeveryEditFieldLabel = uilabel(app.SwitchingTab);
            app.TickeveryEditFieldLabel.Text = 'Tick every';
            app.SWtickTime = uieditfield(app.SwitchingTab, 'numeric');
            app.SWtickTime.ValueDisplayFormat = '%.2f';
            app.SWtickTime.Value = 10;
            app.ControlperiodforallchannelEditFieldLabel = uilabel(app.SwitchingTab);
            app.ControlperiodforallchannelEditFieldLabel.Text = 'Control period for all channel';
            app.SWctrlTime = uieditfield(app.SwitchingTab, 'numeric');
            app.SWctrlTime.ValueDisplayFormat = '%.2f';
            app.SWctrlTime.Value = 10;
            app.StimdurationforallchannelEditFieldLabel = uilabel(app.SwitchingTab);
            app.StimdurationforallchannelEditFieldLabel.Text = 'Stim duration for all channel';
            app.SWstimTime = uieditfield(app.SwitchingTab, 'numeric');
            app.SWstimTime.ValueDisplayFormat = '%.2f';
            app.SWstimTime.Value = 10;
            app.SWcurrTable = uitable(app.SwitchingTab);
            app.SWcurrTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.SWcurrTable.RowName = {};
            app.SWcurrTable.ColumnEditable = true;
            app.WithXcurrentmALabel = uilabel(app.SwitchingTab);
            app.WithXcurrentmALabel.Text = 'With X current (mA)';
            app.HrMinSecLabel = uilabel(app.SwitchingTab);
            app.HrMinSecLabel.Text = 'Hr/Min/Sec';
            app.HrMinSecLabel_2 = uilabel(app.SwitchingTab);
            app.HrMinSecLabel_2.Text = 'Hr/Min/Sec';
            app.WillberoundedupifnotdivisiblebytickLabel = uilabel(app.SwitchingTab);
            app.WillberoundedupifnotdivisiblebytickLabel.Text = 'Will be rounded up if not divisible by tick';
            app.WillberoundedupifnotdivisiblebytickLabel_2 = uilabel(app.SwitchingTab);
            app.WillberoundedupifnotdivisiblebytickLabel_2.Text = 'Will be rounded up if not divisible by tick';
            app.HrMinSecLabel_3 = uilabel(app.SwitchingTab);
            app.HrMinSecLabel_3.Text = 'Hr/Min/Sec';
            app.NoteStimulationunitstoresmaxof5000timeponitsLabel = uilabel(app.SwitchingTab);
            app.NoteStimulationunitstoresmaxof5000timeponitsLabel.Text = 'Note: Stimulation unit stores max of 5000 time ponits.';
            app.ProgramModeSwitch = uibuttongroup(app.ProgramTab);
            app.ProgramModeSwitch.Title = 'Program Mode';
            app.ArbButton = uiradiobutton(app.ProgramModeSwitch);
            app.ArbButton.Text = 'Arb';
            app.ArbButton.Value = true;
            app.SWButton = uiradiobutton(app.ProgramModeSwitch);
            app.SWButton.Text = 'SW';
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.VoltPlot = uiaxes(app.OverviewTab);
            title(app.VoltPlot, 'Voltage history')
            xlabel(app.VoltPlot, 'Time(hr)')
            ylabel(app.VoltPlot, 'Source voltage (V)')
            zlabel(app.VoltPlot, 'Z')
            app.VoltPlot.XLim = [0 5];
            app.VoltPlot.YLim = [-20 20];
            app.CurrPlot = uiaxes(app.OverviewTab);
            title(app.CurrPlot, 'Current history')
            xlabel(app.CurrPlot, 'Time (hr)')
            ylabel(app.CurrPlot, 'Source current (mA)')
            zlabel(app.CurrPlot, 'Z')
            app.CurrPlot.XLim = [0 5];
            app.CurrPlot.YLim = [-2 2];
            app.TimeinplotandlogLabel = uilabel(app.OverviewTab);
            app.TimeinplotandlogLabel.Text = 'Time in plot and log (s) ';
            app.TimeinplotandlogsEditField = uieditfield(app.OverviewTab, 'numeric');
            app.TimeinplotandlogsEditField.ValueDisplayFormat = '%.1f';
            app.DisconnectserialbeforeclearLabel = uilabel(app.OverviewTab);
            app.DisconnectserialbeforeclearLabel.Text = 'Disconnect serial before clear';
            app.ClearallplotButton = uibutton(app.OverviewTab, 'push');
            app.ClearallplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallplotButtonPushed, true);
            app.ClearallplotButton.Text = 'Clear all plot';
            app.Curr8_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr8_disp.Limits = [0 2];
            app.Curr7_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr7_disp.Limits = [0 2];
            app.Curr6_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr6_disp.Limits = [0 2];
            app.Curr5_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr5_disp.Limits = [0 2];
            app.Curr4_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr4_disp.Limits = [0 2];
            app.Curr3_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr3_disp.Limits = [0 2];
            app.Curr2_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr2_disp.Limits = [0 2];
            app.Curr1_disp = uigauge(app.OverviewTab, 'linear');
            app.Curr1_disp.Limits = [0 2];
            app.Volt8_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt8_disp.Limits = [-20 20];
            app.Volt7_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt7_disp.Limits = [-20 20];
            app.Volt6_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt6_disp.Limits = [-20 20];
            app.Volt5_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt5_disp.Limits = [-20 20];
            app.Volt4_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt4_disp.Limits = [-20 20];
            app.Volt3_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt3_disp.Limits = [-20 20];
            app.Volt2_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt2_disp.Limits = [-20 20];
            app.Volt1_disp = uigauge(app.OverviewTab, 'linear');
            app.Volt1_disp.Limits = [-20 20];
            app.SendPulse = uibutton(app.OverviewTab, 'push');
            app.SendPulse.ButtonPushedFcn = createCallbackFcn(app, @SendPulseButtonPushed, true);
            app.SendPulse.Text = 'Send pulse (0.1mA, 5 sec)';
            app.ScrambeforeuseLabel = uilabel(app.OverviewTab);
            app.ScrambeforeuseLabel.Text = 'Scram before use';
            app.secdelayLabel = uilabel(app.OverviewTab);
            app.secdelayLabel.Text = '5 sec delay';
            app.KOhmLabel = uilabel(app.OverviewTab);
            app.KOhmLabel.Text = '(KOhm)';
            app.resTable = uitable(app.OverviewTab);
            app.resTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.resTable.RowName = {};
            app.mALabel = uilabel(app.OverviewTab);
            app.mALabel.Text = '(mA)';
            app.VLabel = uilabel(app.OverviewTab);
            app.VLabel.Text = '(V)';
            app.currTable = uitable(app.OverviewTab);
            app.currTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.currTable.RowName = {};
            app.voltTable = uitable(app.OverviewTab);
            app.voltTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.voltTable.RowName = {};
            app.SincelastcommandsEditFieldLabel = uilabel(app.UIFigure);
            app.SincelastcommandsEditFieldLabel.Text = 'Since last command (s)';
            app.SincelastcommandsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SincelastcommandsEditField.ValueDisplayFormat = '%.1f';
            app.SCRAMButton = uibutton(app.UIFigure, 'push');
            app.SCRAMButton.ButtonPushedFcn = createCallbackFcn(app, @SCRAMButtonPushed, true);
            app.SCRAMButton.Text = 'SCRAM';
            app.SincelastcommandhrsEditFieldLabel = uilabel(app.UIFigure);
            app.SincelastcommandhrsEditFieldLabel.Text = 'Since last command (hrs)';
            app.SincelastcommandhrsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SincelastcommandhrsEditField.ValueDisplayFormat = '%.3f';
            app.LastCommandEditFieldLabel = uilabel(app.UIFigure);
            app.LastCommandEditFieldLabel.Text = 'Last Command';
            app.LastCommandEditField = uieditfield(app.UIFigure, 'text');
            app.LastCommandEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Logging_MultiCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end